classdef BCTDiagnosticator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RadiusSpinnerLabel           matlab.ui.control.Label
        RadiusSpinner                matlab.ui.control.Spinner
        DiagnosisButtonGroup         matlab.ui.container.ButtonGroup
        BButton                      matlab.ui.control.ToggleButton
        MButton                      matlab.ui.control.ToggleButton
        TextureSpinnerLabel          matlab.ui.control.Label
        TextureSpinner               matlab.ui.control.Spinner
        PerimeterSpinnerLabel        matlab.ui.control.Label
        PerimeterSpinner             matlab.ui.control.Spinner
        SmoothnessSpinnerLabel       matlab.ui.control.Label
        SmoothnessSpinner            matlab.ui.control.Spinner
        CompactnessSpinnerLabel      matlab.ui.control.Label
        CompactnessSpinner           matlab.ui.control.Spinner
        AreaSpinnerLabel             matlab.ui.control.Label
        AreaSpinner                  matlab.ui.control.Spinner
        ConcavitySpinnerLabel        matlab.ui.control.Label
        ConcavitySpinner             matlab.ui.control.Spinner
        ConcavePointsSpinnerLabel    matlab.ui.control.Label
        ConcavePointsSpinner         matlab.ui.control.Spinner
        SymmetrySpinnerLabel         matlab.ui.control.Label
        SymmetrySpinner              matlab.ui.control.Spinner
        FractalDimSpinnerLabel       matlab.ui.control.Label
        FractalDimSpinner            matlab.ui.control.Spinner
        MeanLabel                    matlab.ui.control.Label
        StandardErrorLabel           matlab.ui.control.Label
        RadiusSpinner_2Label         matlab.ui.control.Label
        RadiusSpinner_2              matlab.ui.control.Spinner
        TextureSpinner_2Label        matlab.ui.control.Label
        TextureSpinner_2             matlab.ui.control.Spinner
        PerimeterSpinner_2Label      matlab.ui.control.Label
        PerimeterSpinner_2           matlab.ui.control.Spinner
        AreaSpinner_2Label           matlab.ui.control.Label
        AreaSpinner_2                matlab.ui.control.Spinner
        SmoothnessSpinner_2Label     matlab.ui.control.Label
        SmoothnessSpinner_2          matlab.ui.control.Spinner
        CompactnessSpinner_2Label    matlab.ui.control.Label
        CompactnessSpinner_2         matlab.ui.control.Spinner
        ConcavitySpinner_2Label      matlab.ui.control.Label
        ConcavitySpinner_2           matlab.ui.control.Spinner
        ConcavePointsSpinner_2Label  matlab.ui.control.Label
        ConcavePointsSpinner_2       matlab.ui.control.Spinner
        SymmetrySpinner_2Label       matlab.ui.control.Label
        SymmetrySpinner_2            matlab.ui.control.Spinner
        FractalDimSpinner_2Label     matlab.ui.control.Label
        FractalDimSpinner_2          matlab.ui.control.Spinner
        FractalDimSpinner_3Label     matlab.ui.control.Label
        FractalDimSpinner_3          matlab.ui.control.Spinner
        SymmetrySpinner_3Label       matlab.ui.control.Label
        SymmetrySpinner_3            matlab.ui.control.Spinner
        ConcavePointsSpinner_3Label  matlab.ui.control.Label
        ConcavePointsSpinner_3       matlab.ui.control.Spinner
        ConcavitySpinner_3Label      matlab.ui.control.Label
        ConcavitySpinner_3           matlab.ui.control.Spinner
        CompactnessSpinner_3Label    matlab.ui.control.Label
        CompactnessSpinner_3         matlab.ui.control.Spinner
        SmoothnessSpinner_3Label     matlab.ui.control.Label
        SmoothnessSpinner_3          matlab.ui.control.Spinner
        AreaSpinner_3Label           matlab.ui.control.Label
        AreaSpinner_3                matlab.ui.control.Spinner
        PerimeterSpinner_3Label      matlab.ui.control.Label
        PerimeterSpinner_3           matlab.ui.control.Spinner
        TextureSpinner_3Label        matlab.ui.control.Label
        TextureSpinner_3             matlab.ui.control.Spinner
        RadiusSpinner_3Label         matlab.ui.control.Label
        RadiusSpinner_3              matlab.ui.control.Spinner
        WorstLabel                   matlab.ui.control.Label
        IDSpinnerLabel               matlab.ui.control.Label
        IDSpinner                    matlab.ui.control.Spinner
        SavePatientButton            matlab.ui.control.Button
        FileEditFieldLabel           matlab.ui.control.Label
        FileEditField                matlab.ui.control.EditField
        dataLabel                    matlab.ui.control.Label
        SaveFileButton               matlab.ui.control.Button
        FileEditField_2Label         matlab.ui.control.Label
        FileEditField_2              matlab.ui.control.EditField
        dataLabel_2                  matlab.ui.control.Label
        LoadFileButton               matlab.ui.control.Button
        PredictdiagnosisButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        PatientList = [];
        Diag = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SavePatientButton
        function SavePatientButtonPushed(app, event)

            if app.BButton.Value
                 app.Diag = 0;
                 
            elseif app.MButton.Value
                 app.Diag = 1;
            end
            
            if size(app.PatientList,1) > 0
                overID = app.PatientList(:,1) == app.IDSpinner.Value;
                
                if sum(overID) == 0
                    app.PatientList(end+1,:) = [app.IDSpinner.Value,app.Diag,app.RadiusSpinner.Value,app.TextureSpinner.Value,app.PerimeterSpinner.Value,...
                        app.AreaSpinner.Value,app.SmoothnessSpinner.Value,app.CompactnessSpinner.Value,app.ConcavitySpinner.Value,...
                        app.ConcavePointsSpinner.Value,app.SymmetrySpinner.Value,app.FractalDimSpinner.Value,...
                        app.RadiusSpinner_2.Value,app.TextureSpinner_2.Value,app.PerimeterSpinner_2.Value,app.AreaSpinner_2.Value,...
                        app.SmoothnessSpinner_2.Value,app.CompactnessSpinner_2.Value,app.ConcavitySpinner_2.Value,...
                        app.ConcavePointsSpinner_2.Value,app.SymmetrySpinner_2.Value,app.FractalDimSpinner_2.Value,...
                        app.RadiusSpinner_3.Value,app.TextureSpinner_3.Value,app.PerimeterSpinner_3.Value,app.AreaSpinner_3.Value,...
                        app.SmoothnessSpinner_3.Value,app.CompactnessSpinner_3.Value,app.ConcavitySpinner_3.Value,...
                        app.ConcavePointsSpinner_3.Value,app.SymmetrySpinner_3.Value,app.FractalDimSpinner_3.Value];
                    
                elseif sum(overID) == 1
                    app.PatientList(overID,:) = [app.IDSpinner.Value,app.Diag,app.RadiusSpinner.Value,app.TextureSpinner.Value,app.PerimeterSpinner.Value,...
                        app.AreaSpinner.Value,app.SmoothnessSpinner.Value,app.CompactnessSpinner.Value,app.ConcavitySpinner.Value,...
                        app.ConcavePointsSpinner.Value,app.SymmetrySpinner.Value,app.FractalDimSpinner.Value,...
                        app.RadiusSpinner_2.Value,app.TextureSpinner_2.Value,app.PerimeterSpinner_2.Value,app.AreaSpinner_2.Value,...
                        app.SmoothnessSpinner_2.Value,app.CompactnessSpinner_2.Value,app.ConcavitySpinner_2.Value,...
                        app.ConcavePointsSpinner_2.Value,app.SymmetrySpinner_2.Value,app.FractalDimSpinner_2.Value,...
                        app.RadiusSpinner_3.Value,app.TextureSpinner_3.Value,app.PerimeterSpinner_3.Value,app.AreaSpinner_3.Value,...
                        app.SmoothnessSpinner_3.Value,app.CompactnessSpinner_3.Value,app.ConcavitySpinner_3.Value,...
                        app.ConcavePointsSpinner_3.Value,app.SymmetrySpinner_3.Value,app.FractalDimSpinner_3.Value];
                end
            else
                    app.PatientList(end+1,:) = [app.IDSpinner.Value,app.Diag,app.RadiusSpinner.Value,app.TextureSpinner.Value,app.PerimeterSpinner.Value,...
                        app.AreaSpinner.Value,app.SmoothnessSpinner.Value,app.CompactnessSpinner.Value,app.ConcavitySpinner.Value,...
                        app.ConcavePointsSpinner.Value,app.SymmetrySpinner.Value,app.FractalDimSpinner.Value,...
                        app.RadiusSpinner_2.Value,app.TextureSpinner_2.Value,app.PerimeterSpinner_2.Value,app.AreaSpinner_2.Value,...
                        app.SmoothnessSpinner_2.Value,app.CompactnessSpinner_2.Value,app.ConcavitySpinner_2.Value,...
                        app.ConcavePointsSpinner_2.Value,app.SymmetrySpinner_2.Value,app.FractalDimSpinner_2.Value,...
                        app.RadiusSpinner_3.Value,app.TextureSpinner_3.Value,app.PerimeterSpinner_3.Value,app.AreaSpinner_3.Value,...
                        app.SmoothnessSpinner_3.Value,app.CompactnessSpinner_3.Value,app.ConcavitySpinner_3.Value,...
                        app.ConcavePointsSpinner_3.Value,app.SymmetrySpinner_3.Value,app.FractalDimSpinner_3.Value];
           end
        end

        % Button pushed function: PredictdiagnosisButton
        function PredictdiagnosisButtonPushed(app, event)
            data = [app.RadiusSpinner.Value,app.TextureSpinner.Value,app.PerimeterSpinner.Value,...
                    app.AreaSpinner.Value,app.SmoothnessSpinner.Value,app.CompactnessSpinner.Value,app.ConcavitySpinner.Value,...
                    app.ConcavePointsSpinner.Value,app.SymmetrySpinner.Value,app.FractalDimSpinner.Value,...
                    app.RadiusSpinner_2.Value,app.TextureSpinner_2.Value,app.PerimeterSpinner_2.Value,app.AreaSpinner_2.Value,...
                    app.SmoothnessSpinner_2.Value,app.CompactnessSpinner_2.Value,app.ConcavitySpinner_2.Value,...
                    app.ConcavePointsSpinner_2.Value,app.SymmetrySpinner_2.Value,app.FractalDimSpinner_2.Value,...
                    app.RadiusSpinner_3.Value,app.TextureSpinner_3.Value,app.PerimeterSpinner_3.Value,app.AreaSpinner_3.Value,...
                    app.SmoothnessSpinner_3.Value,app.CompactnessSpinner_3.Value,app.ConcavitySpinner_3.Value,...
                    app.ConcavePointsSpinner_3.Value,app.SymmetrySpinner_3.Value,app.FractalDimSpinner_3.Value];
                
            load('TrainedSVMModel.mat');
            predicted_class = predict(model,data);
            if predicted_class == 'B'
                app.BButton.Value = true;
                app.Diag = 0;
            elseif predicted_class == 'M'
                app.MButton.Value = true;
                app.Diag = 1;
            end
        end

        % Value changed function: RadiusSpinner
        function RadiusSpinnerValueChanged(app, event)
            
        end

        % Value changed function: TextureSpinner
        function TextureSpinnerValueChanged(app, event)
            
        end

        % Value changed function: PerimeterSpinner
        function PerimeterSpinnerValueChanged(app, event)
            
        end

        % Value changed function: AreaSpinner
        function AreaSpinnerValueChanged(app, event)
            
        end

        % Value changed function: SmoothnessSpinner
        function SmoothnessSpinnerValueChanged(app, event)
            
        end

        % Value changed function: CompactnessSpinner
        function CompactnessSpinnerValueChanged(app, event)
            
        end

        % Value changed function: ConcavitySpinner
        function ConcavitySpinnerValueChanged(app, event)
            
        end

        % Value changed function: ConcavePointsSpinner
        function ConcavePointsSpinnerValueChanged(app, event)
            
        end

        % Value changed function: SymmetrySpinner
        function SymmetrySpinnerValueChanged(app, event)
            
        end

        % Value changed function: FractalDimSpinner
        function FractalDimSpinnerValueChanged(app, event)
            
        end

        % Value changed function: RadiusSpinner_2
        function RadiusSpinner_2ValueChanged(app, event)
            
        end

        % Value changed function: TextureSpinner_2
        function TextureSpinner_2ValueChanged(app, event)
            
        end

        % Value changed function: PerimeterSpinner_2
        function PerimeterSpinner_2ValueChanged(app, event)
            
        end

        % Value changed function: AreaSpinner_2
        function AreaSpinner_2ValueChanged(app, event)
            
        end

        % Value changed function: SmoothnessSpinner_2
        function SmoothnessSpinner_2ValueChanged(app, event)
            
        end

        % Value changed function: CompactnessSpinner_2
        function CompactnessSpinner_2ValueChanged(app, event)
            
        end

        % Value changed function: ConcavitySpinner_2
        function ConcavitySpinner_2ValueChanged(app, event)
            
        end

        % Value changed function: ConcavePointsSpinner_2
        function ConcavePointsSpinner_2ValueChanged(app, event)
            
        end

        % Value changed function: SymmetrySpinner_2
        function SymmetrySpinner_2ValueChanged(app, event)
            
        end

        % Value changed function: FractalDimSpinner_2
        function FractalDimSpinner_2ValueChanged(app, event)
            
        end

        % Value changed function: RadiusSpinner_3
        function RadiusSpinner_3ValueChanged(app, event)
            
        end

        % Value changed function: TextureSpinner_3
        function TextureSpinner_3ValueChanged(app, event)
            
        end

        % Value changed function: PerimeterSpinner_3
        function PerimeterSpinner_3ValueChanged(app, event)
            
        end

        % Value changed function: AreaSpinner_3
        function AreaSpinner_3ValueChanged(app, event)
            
        end

        % Value changed function: SmoothnessSpinner_3
        function SmoothnessSpinner_3ValueChanged(app, event)
            
        end

        % Value changed function: CompactnessSpinner_3
        function CompactnessSpinner_3ValueChanged(app, event)
            
        end

        % Value changed function: ConcavitySpinner_3
        function ConcavitySpinner_3ValueChanged(app, event)
            
        end

        % Value changed function: ConcavePointsSpinner_3
        function ConcavePointsSpinner_3ValueChanged(app, event)
            
        end

        % Value changed function: SymmetrySpinner_3
        function SymmetrySpinner_3ValueChanged(app, event)
            
        end

        % Value changed function: FractalDimSpinner_3
        function FractalDimSpinner_3ValueChanged(app, event)
            
        end

        % Value changed function: IDSpinner
        function IDSpinnerValueChanged(app, event)
            
        end

        % Button pushed function: SaveFileButton
        function SaveFileButtonPushed(app, event)
            file = fopen(strcat(app.FileEditField.Value,'.data'),'w');
            for i = 1:size(app.PatientList,1)
                d = '';
                if app.PatientList(i,2) == 0
                    d = 'B';
                elseif app.PatientList(i,2) == 1
                    d = 'M';
                end
                fprintf(file,'%d,%1s,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f\n',app.PatientList(i,1),d,app.PatientList(i,3:32));
            end
            fclose(file);
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            filename = strcat(app.FileEditField_2.Value,'.data');
            delimiter = ',';
            
            formatSpec = '%f%C%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%f%[^\n\r]';
            fileID = fopen(filename,'r');
            dataArray = textscan(fileID, formatSpec, 'Delimiter', delimiter, 'TextType', 'string',  'ReturnOnError', false);
            fclose(fileID);
            exmp = table(dataArray{1:end-1}, 'VariableNames', {'ID','Diagnosis','VarName3','VarName4','VarName5','VarName6','VarName7','VarName8','VarName9','VarName10','VarName11','VarName12','VarName13','VarName14','VarName15','VarName16','VarName17','VarName18','VarName19','VarName20','VarName21','VarName22','VarName23','VarName24','VarName25','VarName26','VarName27','VarName28','VarName29','VarName30','VarName31','VarName32'});
            
            clearvars filename delimiter formatSpec fileID dataArray ans;
            
            exmp.Diagnosis = grp2idx(categorical(exmp.Diagnosis))-1; % 1 = Malignant, 0 = Begnin
            app.PatientList = table2array(exmp);
        end

        % Value changed function: FileEditField_2
        function FileEditField_2ValueChanged(app, event)
            
        end

        % Value changed function: FileEditField
        function FileEditFieldValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RadiusSpinnerLabel = uilabel(app.UIFigure);
            app.RadiusSpinnerLabel.Text = 'Radius';
            app.RadiusSpinner = uispinner(app.UIFigure);
            app.RadiusSpinner.Limits = [0 Inf];
            app.RadiusSpinner.ValueDisplayFormat = '%.4f';
            app.RadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @RadiusSpinnerValueChanged, true);
            app.DiagnosisButtonGroup = uibuttongroup(app.UIFigure);
            app.DiagnosisButtonGroup.Title = 'Diagnosis';
            app.BButton = uitogglebutton(app.DiagnosisButtonGroup);
            app.BButton.Text = 'B';
            app.BButton.Value = true;
            app.MButton = uitogglebutton(app.DiagnosisButtonGroup);
            app.MButton.Text = 'M';
            app.TextureSpinnerLabel = uilabel(app.UIFigure);
            app.TextureSpinnerLabel.Text = 'Texture';
            app.TextureSpinner = uispinner(app.UIFigure);
            app.TextureSpinner.Limits = [0 Inf];
            app.TextureSpinner.ValueDisplayFormat = '%.4f';
            app.TextureSpinner.ValueChangedFcn = createCallbackFcn(app, @TextureSpinnerValueChanged, true);
            app.PerimeterSpinnerLabel = uilabel(app.UIFigure);
            app.PerimeterSpinnerLabel.Text = 'Perimeter';
            app.PerimeterSpinner = uispinner(app.UIFigure);
            app.PerimeterSpinner.Limits = [0 Inf];
            app.PerimeterSpinner.ValueDisplayFormat = '%.4f';
            app.PerimeterSpinner.ValueChangedFcn = createCallbackFcn(app, @PerimeterSpinnerValueChanged, true);
            app.SmoothnessSpinnerLabel = uilabel(app.UIFigure);
            app.SmoothnessSpinnerLabel.Text = 'Smoothness';
            app.SmoothnessSpinner = uispinner(app.UIFigure);
            app.SmoothnessSpinner.Limits = [0 Inf];
            app.SmoothnessSpinner.ValueDisplayFormat = '%.4f';
            app.SmoothnessSpinner.ValueChangedFcn = createCallbackFcn(app, @SmoothnessSpinnerValueChanged, true);
            app.CompactnessSpinnerLabel = uilabel(app.UIFigure);
            app.CompactnessSpinnerLabel.Text = 'Compactness';
            app.CompactnessSpinner = uispinner(app.UIFigure);
            app.CompactnessSpinner.Limits = [0 Inf];
            app.CompactnessSpinner.ValueDisplayFormat = '%.4f';
            app.CompactnessSpinner.ValueChangedFcn = createCallbackFcn(app, @CompactnessSpinnerValueChanged, true);
            app.AreaSpinnerLabel = uilabel(app.UIFigure);
            app.AreaSpinnerLabel.Text = 'Area';
            app.AreaSpinner = uispinner(app.UIFigure);
            app.AreaSpinner.Limits = [0 Inf];
            app.AreaSpinner.ValueDisplayFormat = '%.4f';
            app.AreaSpinner.ValueChangedFcn = createCallbackFcn(app, @AreaSpinnerValueChanged, true);
            app.ConcavitySpinnerLabel = uilabel(app.UIFigure);
            app.ConcavitySpinnerLabel.Text = 'Concavity';
            app.ConcavitySpinner = uispinner(app.UIFigure);
            app.ConcavitySpinner.Limits = [0 Inf];
            app.ConcavitySpinner.ValueDisplayFormat = '%.4f';
            app.ConcavitySpinner.ValueChangedFcn = createCallbackFcn(app, @ConcavitySpinnerValueChanged, true);
            app.ConcavePointsSpinnerLabel = uilabel(app.UIFigure);
            app.ConcavePointsSpinnerLabel.Text = 'Concave Points';
            app.ConcavePointsSpinner = uispinner(app.UIFigure);
            app.ConcavePointsSpinner.Limits = [0 Inf];
            app.ConcavePointsSpinner.ValueDisplayFormat = '%.4f';
            app.ConcavePointsSpinner.ValueChangedFcn = createCallbackFcn(app, @ConcavePointsSpinnerValueChanged, true);
            app.SymmetrySpinnerLabel = uilabel(app.UIFigure);
            app.SymmetrySpinnerLabel.Text = 'Symmetry';
            app.SymmetrySpinner = uispinner(app.UIFigure);
            app.SymmetrySpinner.Limits = [0 Inf];
            app.SymmetrySpinner.ValueDisplayFormat = '%.4f';
            app.SymmetrySpinner.ValueChangedFcn = createCallbackFcn(app, @SymmetrySpinnerValueChanged, true);
            app.FractalDimSpinnerLabel = uilabel(app.UIFigure);
            app.FractalDimSpinnerLabel.Text = 'Fractal Dim.';
            app.FractalDimSpinner = uispinner(app.UIFigure);
            app.FractalDimSpinner.Limits = [0 Inf];
            app.FractalDimSpinner.ValueDisplayFormat = '%.4f';
            app.FractalDimSpinner.ValueChangedFcn = createCallbackFcn(app, @FractalDimSpinnerValueChanged, true);
            app.MeanLabel = uilabel(app.UIFigure);
            app.MeanLabel.Text = 'Mean';
            app.StandardErrorLabel = uilabel(app.UIFigure);
            app.StandardErrorLabel.Text = 'Standard Error';
            app.RadiusSpinner_2Label = uilabel(app.UIFigure);
            app.RadiusSpinner_2Label.Text = 'Radius';
            app.RadiusSpinner_2 = uispinner(app.UIFigure);
            app.RadiusSpinner_2.Limits = [0 Inf];
            app.RadiusSpinner_2.ValueDisplayFormat = '%.4f';
            app.RadiusSpinner_2.ValueChangedFcn = createCallbackFcn(app, @RadiusSpinner_2ValueChanged, true);
            app.TextureSpinner_2Label = uilabel(app.UIFigure);
            app.TextureSpinner_2Label.Text = 'Texture';
            app.TextureSpinner_2 = uispinner(app.UIFigure);
            app.TextureSpinner_2.Limits = [0 Inf];
            app.TextureSpinner_2.ValueDisplayFormat = '%.4f';
            app.TextureSpinner_2.ValueChangedFcn = createCallbackFcn(app, @TextureSpinner_2ValueChanged, true);
            app.PerimeterSpinner_2Label = uilabel(app.UIFigure);
            app.PerimeterSpinner_2Label.Text = 'Perimeter';
            app.PerimeterSpinner_2 = uispinner(app.UIFigure);
            app.PerimeterSpinner_2.Limits = [0 Inf];
            app.PerimeterSpinner_2.ValueDisplayFormat = '%.4f';
            app.PerimeterSpinner_2.ValueChangedFcn = createCallbackFcn(app, @PerimeterSpinner_2ValueChanged, true);
            app.AreaSpinner_2Label = uilabel(app.UIFigure);
            app.AreaSpinner_2Label.Text = 'Area';
            app.AreaSpinner_2 = uispinner(app.UIFigure);
            app.AreaSpinner_2.Limits = [0 Inf];
            app.AreaSpinner_2.ValueDisplayFormat = '%.4f';
            app.AreaSpinner_2.ValueChangedFcn = createCallbackFcn(app, @AreaSpinner_2ValueChanged, true);
            app.SmoothnessSpinner_2Label = uilabel(app.UIFigure);
            app.SmoothnessSpinner_2Label.Text = 'Smoothness';
            app.SmoothnessSpinner_2 = uispinner(app.UIFigure);
            app.SmoothnessSpinner_2.Limits = [0 Inf];
            app.SmoothnessSpinner_2.ValueDisplayFormat = '%.4f';
            app.SmoothnessSpinner_2.ValueChangedFcn = createCallbackFcn(app, @SmoothnessSpinner_2ValueChanged, true);
            app.CompactnessSpinner_2Label = uilabel(app.UIFigure);
            app.CompactnessSpinner_2Label.Text = 'Compactness';
            app.CompactnessSpinner_2 = uispinner(app.UIFigure);
            app.CompactnessSpinner_2.Limits = [0 Inf];
            app.CompactnessSpinner_2.ValueDisplayFormat = '%.4f';
            app.CompactnessSpinner_2.ValueChangedFcn = createCallbackFcn(app, @CompactnessSpinner_2ValueChanged, true);
            app.ConcavitySpinner_2Label = uilabel(app.UIFigure);
            app.ConcavitySpinner_2Label.Text = 'Concavity';
            app.ConcavitySpinner_2 = uispinner(app.UIFigure);
            app.ConcavitySpinner_2.Limits = [0 Inf];
            app.ConcavitySpinner_2.ValueDisplayFormat = '%.4f';
            app.ConcavitySpinner_2.ValueChangedFcn = createCallbackFcn(app, @ConcavitySpinner_2ValueChanged, true);
            app.ConcavePointsSpinner_2Label = uilabel(app.UIFigure);
            app.ConcavePointsSpinner_2Label.Text = 'Concave Points';
            app.ConcavePointsSpinner_2 = uispinner(app.UIFigure);
            app.ConcavePointsSpinner_2.Limits = [0 Inf];
            app.ConcavePointsSpinner_2.ValueDisplayFormat = '%.4f';
            app.ConcavePointsSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ConcavePointsSpinner_2ValueChanged, true);
            app.SymmetrySpinner_2Label = uilabel(app.UIFigure);
            app.SymmetrySpinner_2Label.Text = 'Symmetry';
            app.SymmetrySpinner_2 = uispinner(app.UIFigure);
            app.SymmetrySpinner_2.Limits = [0 Inf];
            app.SymmetrySpinner_2.ValueDisplayFormat = '%.4f';
            app.SymmetrySpinner_2.ValueChangedFcn = createCallbackFcn(app, @SymmetrySpinner_2ValueChanged, true);
            app.FractalDimSpinner_2Label = uilabel(app.UIFigure);
            app.FractalDimSpinner_2Label.Text = 'Fractal Dim.';
            app.FractalDimSpinner_2 = uispinner(app.UIFigure);
            app.FractalDimSpinner_2.Limits = [0 Inf];
            app.FractalDimSpinner_2.ValueDisplayFormat = '%.4f';
            app.FractalDimSpinner_2.ValueChangedFcn = createCallbackFcn(app, @FractalDimSpinner_2ValueChanged, true);
            app.FractalDimSpinner_3Label = uilabel(app.UIFigure);
            app.FractalDimSpinner_3Label.Text = 'Fractal Dim.';
            app.FractalDimSpinner_3 = uispinner(app.UIFigure);
            app.FractalDimSpinner_3.Limits = [0 Inf];
            app.FractalDimSpinner_3.ValueDisplayFormat = '%.4f';
            app.FractalDimSpinner_3.ValueChangedFcn = createCallbackFcn(app, @FractalDimSpinner_3ValueChanged, true);
            app.SymmetrySpinner_3Label = uilabel(app.UIFigure);
            app.SymmetrySpinner_3Label.Text = 'Symmetry';
            app.SymmetrySpinner_3 = uispinner(app.UIFigure);
            app.SymmetrySpinner_3.Limits = [0 Inf];
            app.SymmetrySpinner_3.ValueDisplayFormat = '%.4f';
            app.SymmetrySpinner_3.ValueChangedFcn = createCallbackFcn(app, @SymmetrySpinner_3ValueChanged, true);
            app.ConcavePointsSpinner_3Label = uilabel(app.UIFigure);
            app.ConcavePointsSpinner_3Label.Text = 'Concave Points';
            app.ConcavePointsSpinner_3 = uispinner(app.UIFigure);
            app.ConcavePointsSpinner_3.Limits = [0 Inf];
            app.ConcavePointsSpinner_3.ValueDisplayFormat = '%.4f';
            app.ConcavePointsSpinner_3.ValueChangedFcn = createCallbackFcn(app, @ConcavePointsSpinner_3ValueChanged, true);
            app.ConcavitySpinner_3Label = uilabel(app.UIFigure);
            app.ConcavitySpinner_3Label.Text = 'Concavity';
            app.ConcavitySpinner_3 = uispinner(app.UIFigure);
            app.ConcavitySpinner_3.Limits = [0 Inf];
            app.ConcavitySpinner_3.ValueDisplayFormat = '%.4f';
            app.ConcavitySpinner_3.ValueChangedFcn = createCallbackFcn(app, @ConcavitySpinner_3ValueChanged, true);
            app.CompactnessSpinner_3Label = uilabel(app.UIFigure);
            app.CompactnessSpinner_3Label.Text = 'Compactness';
            app.CompactnessSpinner_3 = uispinner(app.UIFigure);
            app.CompactnessSpinner_3.Limits = [0 Inf];
            app.CompactnessSpinner_3.ValueDisplayFormat = '%.4f';
            app.CompactnessSpinner_3.ValueChangedFcn = createCallbackFcn(app, @CompactnessSpinner_3ValueChanged, true);
            app.SmoothnessSpinner_3Label = uilabel(app.UIFigure);
            app.SmoothnessSpinner_3Label.Text = 'Smoothness';
            app.SmoothnessSpinner_3 = uispinner(app.UIFigure);
            app.SmoothnessSpinner_3.Limits = [0 Inf];
            app.SmoothnessSpinner_3.ValueDisplayFormat = '%.4f';
            app.SmoothnessSpinner_3.ValueChangedFcn = createCallbackFcn(app, @SmoothnessSpinner_3ValueChanged, true);
            app.AreaSpinner_3Label = uilabel(app.UIFigure);
            app.AreaSpinner_3Label.Text = 'Area';
            app.AreaSpinner_3 = uispinner(app.UIFigure);
            app.AreaSpinner_3.Limits = [0 Inf];
            app.AreaSpinner_3.ValueDisplayFormat = '%.4f';
            app.AreaSpinner_3.ValueChangedFcn = createCallbackFcn(app, @AreaSpinner_3ValueChanged, true);
            app.PerimeterSpinner_3Label = uilabel(app.UIFigure);
            app.PerimeterSpinner_3Label.Text = 'Perimeter';
            app.PerimeterSpinner_3 = uispinner(app.UIFigure);
            app.PerimeterSpinner_3.Limits = [0 Inf];
            app.PerimeterSpinner_3.ValueDisplayFormat = '%.4f';
            app.PerimeterSpinner_3.ValueChangedFcn = createCallbackFcn(app, @PerimeterSpinner_3ValueChanged, true);
            app.TextureSpinner_3Label = uilabel(app.UIFigure);
            app.TextureSpinner_3Label.Text = 'Texture';
            app.TextureSpinner_3 = uispinner(app.UIFigure);
            app.TextureSpinner_3.Limits = [0 Inf];
            app.TextureSpinner_3.ValueDisplayFormat = '%.4f';
            app.TextureSpinner_3.ValueChangedFcn = createCallbackFcn(app, @TextureSpinner_3ValueChanged, true);
            app.RadiusSpinner_3Label = uilabel(app.UIFigure);
            app.RadiusSpinner_3Label.Text = 'Radius';
            app.RadiusSpinner_3 = uispinner(app.UIFigure);
            app.RadiusSpinner_3.Limits = [0 Inf];
            app.RadiusSpinner_3.ValueDisplayFormat = '%.4f';
            app.RadiusSpinner_3.ValueChangedFcn = createCallbackFcn(app, @RadiusSpinner_3ValueChanged, true);
            app.WorstLabel = uilabel(app.UIFigure);
            app.WorstLabel.Text = 'Worst';
            app.IDSpinnerLabel = uilabel(app.UIFigure);
            app.IDSpinnerLabel.Text = 'ID';
            app.IDSpinner = uispinner(app.UIFigure);
            app.IDSpinner.Limits = [0 Inf];
            app.IDSpinner.ValueDisplayFormat = '%.0f';
            app.IDSpinner.ValueChangedFcn = createCallbackFcn(app, @IDSpinnerValueChanged, true);
            app.SavePatientButton = uibutton(app.UIFigure, 'push');
            app.SavePatientButton.ButtonPushedFcn = createCallbackFcn(app, @SavePatientButtonPushed, true);
            app.SavePatientButton.Text = 'Save Patient';
            app.FileEditFieldLabel = uilabel(app.UIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.UIFigure, 'text');
            app.FileEditField.ValueChangedFcn = createCallbackFcn(app, @FileEditFieldValueChanged, true);
            app.FileEditField.Value = 'File_to_save';
            app.dataLabel = uilabel(app.UIFigure);
            app.dataLabel.Text = '.data';
            app.SaveFileButton = uibutton(app.UIFigure, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Text = 'Save File';
            app.FileEditField_2Label = uilabel(app.UIFigure);
            app.FileEditField_2Label.Text = 'File';
            app.FileEditField_2 = uieditfield(app.UIFigure, 'text');
            app.FileEditField_2.ValueChangedFcn = createCallbackFcn(app, @FileEditField_2ValueChanged, true);
            app.FileEditField_2.Value = 'File_to_load';
            app.dataLabel_2 = uilabel(app.UIFigure);
            app.dataLabel_2.Text = '.data';
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.PredictdiagnosisButton = uibutton(app.UIFigure, 'push');
            app.PredictdiagnosisButton.ButtonPushedFcn = createCallbackFcn(app, @PredictdiagnosisButtonPushed, true);
            app.PredictdiagnosisButton.Text = 'Predict diagnosis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BCTDiagnosticator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end